classdef ScopeTrainerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScopeTrainerAppUIFigure     matlab.ui.Figure
        SignalsMeasuredLabel        matlab.ui.control.Label
        OffsetVoltsEditFieldLabel   matlab.ui.control.Label
        AmplitudeVPPEditFieldLabel  matlab.ui.control.Label
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        time_label                  matlab.ui.control.Label
        Gauge                       matlab.ui.control.SemicircularGauge
        StartButton                 matlab.ui.control.Button
        OffsetLamp                  matlab.ui.control.Lamp
        AmpLamp                     matlab.ui.control.Lamp
        Lamp_3Label                 matlab.ui.control.Label
        FreqLamp                    matlab.ui.control.Lamp
        OffsetVoltsEditField        matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        AmplitudeVPPEditField       matlab.ui.control.EditField
        FrequencyHzEditField        matlab.ui.control.EditField
        ConnectLamp                 matlab.ui.control.Lamp
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        fre_guess = 0;% App-Global variables to store user input and compare
        amp_guess = 0;
        off_guess = 0; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function Start(app, event)
            app.StartButton.Text = "Running";
            app.StartButton.Enable = false;
            l = visadevlist; 
            for i = 1:height(l) % selecting function generator
                if l{i,4} == "33509B"
                    res = i;
                end
            end
            v = visadev(l{res,1}); % connecting to generator
            j=0;
            while j < 5
                writeline(v, "*IDN?")
                if contains(readline(v), "33509") % check if generator is ready
                    break
                end
                j=j+1; % try 5 times
            end
            if j == 5
                error("Cannot connect to waveform generator");
            end
            app.ConnectLamp.Color = [0 1 0];
            disp('Connected!');
            writeline(v,"OUTP:LOAD INF"); % setting High-Z mode
            flush(v);
            writeline(v,"DISP OFF"); % turning off front display
            connectbit = 1;
            flush(v);
            pause(2);

            waveforms = ["SIN";"SQU";"TRI";"RAMP";"NRAMP"];
            greenbit = 0;
            freqbit = 0;
            ampbit = 0;
            offbit = 0;
            app.FrequencyHzEditField.Value = "0";
            app.AmplitudeVPPEditField.Value ="0";
            app.OffsetVoltsEditField.Value = "0";
            app.Gauge.Value = 0;
            warning('off','instrument:qcinstrument:realValueIsDifferent');
            if connectbit == 1
                tic;
                for i = 0:4
                    rng("shuffle"); % make random number sequence truly random
                    greenbit = 0;
                    fre =  4 + rand(1)*5*10^(randi(3,1));%random frequency between 4 & 5,004 Hz
                    amp = 0.25+(rand(1))*4.75; %random amplitude between 0.25 and 5
                    off = sign((rand(1)-0.5))*(rand(1)+0.1)*2; %random offset between -2.5 & 2.5 volts
                    wave = waveforms(randi(3,1)); %select waveform
                    if i==4 % sends the final noisy waveform
                        writeline(v,"SOURce1:FUNC NOIS");
                        writeline(v,"SOURce1:VOLTage " + string(amp)); % controls underlying wave amplitude
                        writeline(v,"SOURce1:VOLTage:OFFS " + string(off)); % controls underlying wave offset
                        writeline(v,"SOURce1:SUM:AMPLitude +100.0");
                        writeline(v,"SOURce1:SUM:STATe 1");
                        fre = 100 + rand(1)*10^(randi(3,1)); % frequency between 100 and 1100 Hz
                        writeline(v,"SOURce1:SUM:INTernal:FREQuency "+string(fre));
                    else % sends the 'clean' waveforms
                        writeline(v,"APPLy:" + wave + " " + string(fre) + ", " + string(amp) + ", " + string(off));
                        % writeline(v,"APPLy:" + wave + " " + string(fre) + ", " + string(amp) + ", " + string(off));
                    end
                    disp('Wave ' + string(i+1) + ' generated. Use the oscilloscope to enter the frequency, ampiltude and offset into the app.');
                    
                    while greenbit == 0
                        pause(0.05); % pause to see if values update
                        % replaced with callback
                        %fre_guess = app.FrequencyHzEditField.Value;
                        %amp_guess = app.AmplitudeVPPEditField.Value;
                        %off_guess = app.OffsetVoltsEditField.Value;
                        if app.fre_guess >= 0.9*fre && app.fre_guess <= 1.1*fre
                            % assignin("base",'freq',app.FrequencyHzEditField.Value);
                            app.FreqLamp.Color = [0 1 0];
                            freqbit = 1;
                        else
                            % assignin("base",'freq',app.FrequencyHzEditField.Value);
                            app.FreqLamp.Color = [1 0 0];
                            freqbit = 0;
                        end
                        if app.amp_guess >= 0.9*amp && app.amp_guess <= 1.1*amp
                            % assignin("base",'amp',app.AmplitudeVPPEditField.Value);
                            app.AmpLamp.Color = [0 1 0];
                            ampbit = 1;
                        else
                            % assignin("base",'amp',app.AmplitudeVPPEditField.Value);
                            app.AmpLamp.Color = [1 0 0];
                            ampbit = 0;
                        end
                        if abs(app.off_guess) >= abs(0.9*off) && abs(app.off_guess) <= abs(1.1*off)
                            % assignin("base",'off',app.OffsetVoltsEditField.Value);
                            app.OffsetLamp.Color = [0 1 0];
                            offbit = 1;
                        else
                            % assignin("base",'off',app.OffsetVoltsEditField.Value);
                            app.OffsetLamp.Color = [1 0 0];
                            offbit = 0;
                        end
                        if offbit == 1 && freqbit == 1 && ampbit == 1
                            greenbit = 1;
                            % assignin("base",'greenbit',greenbit);
                        else
                            greenbit = 0;
                            % assignin("base",'greenbit',greenbit);
                        end
                        app.time_label.Text = string(round(toc,2)); % updating timer
                    end
                    app.Gauge.Value = i+1;
                    focus(app.FrequencyHzEditField);
                    pause(0.1);
                    app.FrequencyHzEditField.Value = "0";
                    app.AmplitudeVPPEditField.Value ="0";
                    app.OffsetVoltsEditField.Value = "0";
                    app.fre_guess = 0;
                    app.amp_guess = 0;
                    app.off_guess = 0;
                    app.FrequencyHzEditField.Value = "0";
                    app.AmplitudeVPPEditField.Value ="0";
                    app.OffsetVoltsEditField.Value = "0";
                    % assignin("base",'gauge',app.Gauge.Value);
                end
                disp('DONE!'); % display finish message
                writeline(v,'OUTP OFF'); % turn off function generator
                flush(v); % flush cache on connection
                clear v;
                app.ConnectLamp.Color = [1 0 0];
                app.StartButton.Text = "Start";
                app.StartButton.Enable = true;
            end
        end

        % Value changing function: FrequencyHzEditField, 
        % ...and 1 other component
        function AnyEditFieldValueChanging(app, event)
            changingValue = event.Value;
            numValue = str2double(changingValue);
            if ~isnan(numValue)
                if event.Source == app.FrequencyHzEditField
                    app.fre_guess = numValue;
                end
                if event.Source == app.AmplitudeVPPEditField
                    app.amp_guess = numValue;
                end
                if event.Source == app.OffsetVoltsEditField
                    app.off_guess = numValue;
                end
            end
        end

        % Callback function: AmplitudeVPPEditField, AmplitudeVPPEditField, 
        % ...and 2 other components
        function AnyEditFieldValueChanged(app, event)
            value = event.Value;
            numValue = str2double(value);
            if ~isnan(numValue)
                if event.Source == app.FrequencyHzEditField
                    app.fre_guess = numValue;
                end
                if event.Source == app.AmplitudeVPPEditField
                    app.amp_guess = numValue;
                end
                if event.Source == app.OffsetVoltsEditField
                    app.off_guess = numValue;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScopeTrainerAppUIFigure = uifigure('Visible', 'off');
            app.ScopeTrainerAppUIFigure.Name = 'ScopeTrainer App';
            app.Label = uilabel(app.ScopeTrainerAppUIFigure);
            app.Label.Text = '';
            app.ConnectLamp = uilamp(app.ScopeTrainerAppUIFigure);
            app.FrequencyHzEditField = uieditfield(app.ScopeTrainerAppUIFigure, 'text');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @AnyEditFieldValueChanged, true);
            app.FrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @AnyEditFieldValueChanging, true);
            app.FrequencyHzEditField.Placeholder = '0';
            app.AmplitudeVPPEditField = uieditfield(app.ScopeTrainerAppUIFigure, 'text');
            app.AmplitudeVPPEditField.ValueChangedFcn = createCallbackFcn(app, @AnyEditFieldValueChanged, true);
            app.AmplitudeVPPEditField.ValueChangingFcn = createCallbackFcn(app, @AnyEditFieldValueChanged, true);
            app.AmplitudeVPPEditField.Value = '0';
            app.Label_2 = uilabel(app.ScopeTrainerAppUIFigure);
            app.Label_2.Text = '';
            app.OffsetVoltsEditField = uieditfield(app.ScopeTrainerAppUIFigure, 'text');
            app.OffsetVoltsEditField.ValueChangedFcn = createCallbackFcn(app, @AnyEditFieldValueChanged, true);
            app.OffsetVoltsEditField.ValueChangingFcn = createCallbackFcn(app, @AnyEditFieldValueChanging, true);
            app.OffsetVoltsEditField.Value = '0';
            app.FreqLamp = uilamp(app.ScopeTrainerAppUIFigure);
            app.Lamp_3Label = uilabel(app.ScopeTrainerAppUIFigure);
            app.Lamp_3Label.Text = '';
            app.AmpLamp = uilamp(app.ScopeTrainerAppUIFigure);
            app.OffsetLamp = uilamp(app.ScopeTrainerAppUIFigure);
            app.StartButton = uibutton(app.ScopeTrainerAppUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.Gauge = uigauge(app.ScopeTrainerAppUIFigure, 'semicircular');
            app.Gauge.Limits = [0 5];
            app.Gauge.MinorTicksMode = 'manual';
            app.time_label = uilabel(app.ScopeTrainerAppUIFigure);
            app.time_label.Text = '0';
            app.FrequencyHzEditFieldLabel = uilabel(app.ScopeTrainerAppUIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.AmplitudeVPPEditFieldLabel = uilabel(app.ScopeTrainerAppUIFigure);
            app.AmplitudeVPPEditFieldLabel.Text = 'Amplitude (VPP)';
            app.OffsetVoltsEditFieldLabel = uilabel(app.ScopeTrainerAppUIFigure);
            app.OffsetVoltsEditFieldLabel.Text = 'Offset (Volts)';
            app.SignalsMeasuredLabel = uilabel(app.ScopeTrainerAppUIFigure);
            app.SignalsMeasuredLabel.Text = 'Signals Measured';
            % Show the figure after all components are created
            app.ScopeTrainerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScopeTrainerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScopeTrainerAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScopeTrainerAppUIFigure)
        end
    end
end