classdef FilterTrainerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilterTrainerAppUIFigure        matlab.ui.Figure
        AttemptsRemainingEditField      matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        OscopeReminderLabel             matlab.ui.control.Label
        ResistorValueLabel              matlab.ui.control.Label
        NextSignalButton                matlab.ui.control.Button
        ValidFilterLabel                matlab.ui.control.Label
        ValidFilterLamp                 matlab.ui.control.Lamp
        SignalsFilteredGauge            matlab.ui.control.LinearGauge
        SignalsFilteredGaugeLabel       matlab.ui.control.Label
        ResistorLamp                    matlab.ui.control.Lamp
        FilterLamp                      matlab.ui.control.Lamp
        ResistorValueEditField          matlab.ui.control.EditField
        CapacitorValuenFEditField       matlab.ui.control.NumericEditField
        CapacitorValuenFEditFieldLabel  matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        FilterTypeDropDownLabel         matlab.ui.control.Label
        SignaltoKeepFrequencyHzLabel    matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        FreqLamp                        matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.EditField
        ConnectLamp                     matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %% ================= CONFIG (edit these) =================
        max_attenuation = 0.9       % required |H| at the signal frequency
        c_val           = 100e-9    % fixed capacitor [F]
        freq_tol        = 0.10      % +/- fractional tolerance on frequency guess
        res_tol         = 0.30      % +/- fractional tolerance on resistor guess
        amp             = 2.5       % generator amplitude [Vpk-ish, per APPLy]
        offset          = 0         % generator DC offset
        max_attempts    = 20        % max number of guesses, distorted by updating every character
        wave            = "SIN"     % carrier waveform for every signal
        gen_model       = "33509B"  % visadevlist model string to look for
        gen_idn_match   = "33509"   % substring expected in *IDN? reply
        % One entry per signal. N signals = numel(plan).
        plan = struct( ...
            'type',     {"Low Pass", "Low Pass", "High Pass"}, ...
            'noise_bw', {200000,     200000,     60}, ...
            'freq_fcn', {@() 100  + rand*5*10^randi(3), ...   % noise at HF
                         @() 100  + rand*5*10^randi(3), ...
                         @() 1000 + rand*4*10^randi(3)})      % 60 Hz hum
        %% ================= STATE =================
        gen                         % visadev handle ([] when disconnected)
        sig_idx    = 0              % 0 = idle, 1..N = active signal
        solved     = false          % latched once current signal is valid
        fre_true   = 0              % actual signal frequency [Hz]
        r_ideal    = 0              % ideal resistor for current signal [ohm]
        filt_ideal = ""             % ideal filter type for current signal
        fre_guess  = 0              % student inputs
        r_guess    = 1
        attempts   = 0              % number of attempts remaining for current wave- resets every wave
    end

    %% ---------------- Helpers ----------------
    methods (Access = private)

        function [cutoff_freq, ideal_res] = CalculateIdealFilter(app, freq, type)
            switch type
                case "Low Pass"
                    ideal_res = 1/(2*pi*freq*app.c_val) * sqrt(1/app.max_attenuation^2 - 1);
                case "High Pass"
                    ideal_res = 1/(2*pi*freq*app.c_val) * sqrt(app.max_attenuation^2/(1 - app.max_attenuation^2));
                otherwise
                    ideal_res = 1e6;
                    error("Unknown filter type: %s", type);
            end
            cutoff_freq = 1/(2*pi*ideal_res*app.c_val);
        end

        function c = LampColor(~, ok)
            if ok, c = [0 1 0]; else, c = [1 0 0]; end
        end

        function SetInputsEnabled(app, tf)
            app.FrequencyHzEditField.Editable   = tf;
            app.ResistorValueEditField.Editable = tf;
            app.FilterTypeDropDown.Enable       = tf;
        end

        function UpdatePlot(app)
            R = app.r_guess;
            C = app.c_val;
            switch string(app.FilterTypeDropDown.Value)
                case "Low Pass",  sys = tf(1, [R*C 1]);
                case "High Pass", sys = tf([R*C 0], [R*C 1]);
                otherwise,        return
            end
            [mag_raw, ~, w] = bode(sys);
            freq_Hz = w / (2*pi);
            mag_abs = squeeze(mag_raw);
            semilogx(app.UIAxes, freq_Hz, mag_abs);
            hold(app.UIAxes, 'on');
            if app.fre_guess > 0
                xline(app.UIAxes, app.fre_guess, '--g');
            else
                plot(app.UIAxes, NaN, NaN, '--g');   % keeps legend entries aligned
            end
            xline(app.UIAxes, 1/(2*pi*R*C), '--r');
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, ["Transfer Function" "Freq to Keep" "Filter -3dB Freq"], ...
                'Location', 'best');
        end

        function ClearDisplay(app)
            app.FrequencyHzEditField.Value   = "0";
            app.ResistorValueEditField.Value = "1";
            app.FilterTypeDropDown.Value     = "";
            app.fre_guess                    = 0;
            app.r_guess                      = 1;
            app.ValidFilterLamp.Color        = [1 0 0];
            app.FreqLamp.Color               = [1 0 0];
            app.ResistorLamp.Color           = [1 0 0];
            app.FilterLamp.Color             = [1 0 0];
            app.NextSignalButton.Text        = "Next Signal";
            app.NextSignalButton.Visible     = false;
            app.NextSignalButton.Enable      = false;
            app.OscopeReminderLabel.Visible  = false;
            app.OscopeReminderLabel.Enable   = false;
            cla(app.UIAxes);
            legend(app.UIAxes, 'off');
            focus(app.FrequencyHzEditField);
        end

        function v = ConnectGenerator(app)
            % Find and verify the function generator; throws on failure.
            l   = visadevlist;
            idx = find(string(l{:,4}) == app.gen_model, 1);
            if isempty(idx)
                error("No %s waveform generator found.", app.gen_model);
            end
            v  = visadev(l{idx,1});
            ok = false;
            for k = 1:5
                writeline(v, "*IDN?");
                if contains(readline(v), app.gen_idn_match)
                    ok = true;
                    break
                end
            end
            if ~ok
                error("Cannot connect to waveform generator");
            end
            writeline(v, "OUTP:LOAD INF");   % High-Z mode
            flush(v);
            pause(0.25);
            writeline(v, "DISP OFF");        % front display off
            flush(v);
            pause(1);                        % let the generator settle
        end

        function SendSignal(app, fre, noise_bw)
            v = app.gen;
            writeline(v, "APPLy:" + app.wave + " " + string(fre) + ", " + string(app.amp) + ", " + string(app.offset));
            writeline(v, "SOURce1:SUM:INTernal:FUNC NOIS");
            writeline(v, "FUNC:NOISe:BANDwidth " + string(noise_bw));
            writeline(v, "SOURce1:SUM:AMPLitude +100");
            writeline(v, "SOURce1:SUM:STATe 1");
            writeline(v, "DISP OFF"); 
        end

        function BeginSignal(app, idx)
            % Everything the old outer-loop body did before the while loop.
            app.sig_idx = idx;
            app.solved  = false;
            ClearDisplay(app);
            SetInputsEnabled(app, true);
            app.SignalsFilteredGauge.Value = idx - 1;
            app.attempts = app.max_attempts;
            app.AttemptsRemainingEditField.Value = ceil(app.attempts / 3);

            p = app.plan(idx);
            app.filt_ideal = p.type;
            app.fre_true   = p.freq_fcn();
            [~, app.r_ideal] = CalculateIdealFilter(app, app.fre_true, p.type);
            SendSignal(app, app.fre_true, p.noise_bw);
            disp("Wave " + string(idx) + " generated. Use the oscilloscope to determine your frequency of interest and design your filter.");
        end

        function EvaluateGuess(app)
            % Replaces the polling while-loop. Called after every input change.
            if app.sig_idx == 0
                return
            end
            freqOK = abs(app.fre_guess - app.fre_true) <= app.freq_tol * app.fre_true;
            resOK  = abs(app.r_guess   - app.r_ideal)  <= app.res_tol  * app.r_ideal;
            typeOK = string(app.FilterTypeDropDown.Value) == app.filt_ideal;

            app.FreqLamp.Color     = LampColor(app, freqOK);
            app.ResistorLamp.Color = LampColor(app, resOK);
            app.FilterLamp.Color   = LampColor(app, typeOK);
            

            if freqOK && resOK && typeOK
                SolveSignal(app);
            else
                app.ValidFilterLamp.Color  = LampColor(app, (freqOK && resOK && typeOK));
                app.attempts = app.attempts - 1;
                app.AttemptsRemainingEditField.Value = ceil(app.attempts/3);
            end
            if app.attempts == 0
                uialert(app.FilterTrainerAppUIFigure, ...
                    "Maximum attempts exceeded, generating a new wave. Solve the transfer function for R; don't guess.", ...
                    "Too many guesses", ...
                    "Icon","warning");
                BeginSignal(app, app.sig_idx);
            end
        end

        function SolveSignal(app)
            % Pause for the student to build/document the real filter;
            % NextSignalButton drives what happens next.
            app.solved = true;
            app.SignalsFilteredGauge.Value  = app.sig_idx;
            app.ValidFilterLamp.Color       = [0 1 0];
            app.NextSignalButton.Visible    = true;
            app.NextSignalButton.Enable     = true;
            if app.sig_idx == numel(app.plan)
                app.NextSignalButton.Text   = "Finish";
            end
            app.OscopeReminderLabel.Visible = true;
            app.OscopeReminderLabel.Enable  = true;
        end

        function FinishSession(app)
            disp('DONE!');
            ClearDisplay(app);
            SetInputsEnabled(app, false)
            app.SignalsFilteredGauge.Value = numel(app.plan);
            ShutdownGenerator(app);
            app.sig_idx            = 0;
            app.ConnectLamp.Color  = [1 0 0];
            app.StartButton.Text   = "Start";
            app.StartButton.Enable = true;
        end

        function ShutdownGenerator(app)
            if ~isempty(app.gen)
                try
                    writeline(app.gen, 'OUTP OFF');
                    writeline(app.gen, 'DISP ON');   % restore front display
                    flush(app.gen);
                catch
                end
            end
            app.gen = [];    % releases the visadev connection
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function Start(app, event)
            try
                app.gen = ConnectGenerator(app);
            catch ME
                app.gen = [];
                uialert(app.FilterTrainerAppUIFigure, ME.message, 'Connection error');
                return
            end
            app.StartButton.Text   = "Running";
            app.StartButton.Enable = false;
            app.ConnectLamp.Color  = [0 1 0];
            app.CapacitorValuenFEditField.Value = app.c_val * 1e9;
            disp('Connected!');
            drawnow;

            warning('off','instrument:qcinstrument:realValueIsDifferent');
            rng("shuffle");
            ylabel(app.UIAxes, 'Attenuation / $\frac{V_{out}}{V_{in}}$', 'Interpreter','latex');
            N = numel(app.plan);
            app.SignalsFilteredGauge.Limits     = [0 N];
            app.SignalsFilteredGauge.MajorTicks = 0:N;
            BeginSignal(app, 1);
        end

        % Callback function: FrequencyHzEditField, FrequencyHzEditField, 
        % ...and 2 other components
        function GuessEdited(app, event)
            numValue = str2double(event.Value);
            if isnan(numValue)
                return
            end
            numValue = max(numValue, 1);   % zero/negative breaks the transfer function
            switch event.Source
                case app.FrequencyHzEditField
                    app.fre_guess = numValue;
                case app.ResistorValueEditField
                    app.r_guess = numValue;
            end
            EvaluateGuess(app);
            if app.r_guess > 1
                UpdatePlot(app);
            end
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeChanged(app, event)

            EvaluateGuess(app);
            UpdatePlot(app);
        end

        % Button pushed function: NextSignalButton
        function NextSignalButtonPushed(app, event)
            if app.sig_idx < numel(app.plan)
                BeginSignal(app, app.sig_idx + 1);
            else
                FinishSession(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilterTrainerAppUIFigure = uifigure('Visible', 'off');
            app.FilterTrainerAppUIFigure.Name = 'FilterTrainer App';
            app.UIAxes = uiaxes(app.FilterTrainerAppUIFigure);
            title(app.UIAxes, 'Transfer Function for Selected Filter')
            xlabel(app.UIAxes, 'Frequency / Hz')
            ylabel(app.UIAxes, 'Attenuation')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0.5 100000];
            app.UIAxes.YLim = [0 1.1];
            app.UIAxes.XTick = [0.1 10 100 1000 10000 100000];
            app.UIAxes.XScale = 'log';
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ConnectLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.FrequencyHzEditField = uieditfield(app.FilterTrainerAppUIFigure, 'text');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @GuessEdited, true);
            app.FrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @GuessEdited, true);
            app.FrequencyHzEditField.Editable = 'off';
            app.FrequencyHzEditField.Tooltip = {'Input the frequency that you want to preserve at the output.'};
            app.FrequencyHzEditField.Placeholder = '0';
            app.Label_2 = uilabel(app.FilterTrainerAppUIFigure);
            app.Label_2.Text = '';
            app.FreqLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.StartButton = uibutton(app.FilterTrainerAppUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.SignaltoKeepFrequencyHzLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.SignaltoKeepFrequencyHzLabel.Text = 'Signal Frequency (Hz)';
            app.FilterTypeDropDownLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FilterTrainerAppUIFigure);
            app.FilterTypeDropDown.Items = {'', 'Low Pass', 'High Pass'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeChanged, true);
            app.FilterTypeDropDown.Value = '';
            app.CapacitorValuenFEditFieldLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.CapacitorValuenFEditFieldLabel.Enable = 'off';
            app.CapacitorValuenFEditFieldLabel.Text = 'Capacitor Value (nF)';
            app.CapacitorValuenFEditField = uieditfield(app.FilterTrainerAppUIFigure, 'numeric');
            app.CapacitorValuenFEditField.Limits = [0 Inf];
            app.CapacitorValuenFEditField.AllowEmpty = 'on';
            app.CapacitorValuenFEditField.Editable = 'off';
            app.CapacitorValuenFEditField.Enable = 'off';
            app.CapacitorValuenFEditField.Tooltip = {'You cannot change the capacitor value. Use the capacitor you were provided for this activity.'};
            app.CapacitorValuenFEditField.Value = [];
            app.ResistorValueEditField = uieditfield(app.FilterTrainerAppUIFigure, 'text');
            app.ResistorValueEditField.ValueChangedFcn = createCallbackFcn(app, @GuessEdited, true);
            app.ResistorValueEditField.ValueChangingFcn = createCallbackFcn(app, @GuessEdited, true);
            app.ResistorValueEditField.Editable = 'off';
            app.ResistorValueEditField.Tooltip = {'Use your resistor substitution box to implement your chosen resistor value.'};
            app.FilterLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.ResistorLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.SignalsFilteredGaugeLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.SignalsFilteredGaugeLabel.Text = 'Signals Filtered';
            app.SignalsFilteredGauge = uigauge(app.FilterTrainerAppUIFigure, 'linear');
            app.SignalsFilteredGauge.Limits = [0 3];
            app.SignalsFilteredGauge.MajorTicks = [0 1 2 3];
            app.SignalsFilteredGauge.MinorTicksMode = 'manual';
            app.ValidFilterLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.ValidFilterLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.ValidFilterLabel.Text = 'Valid Filter';
            app.NextSignalButton = uibutton(app.FilterTrainerAppUIFigure, 'push');
            app.NextSignalButton.ButtonPushedFcn = createCallbackFcn(app, @NextSignalButtonPushed, true);
            app.NextSignalButton.Enable = 'off';
            app.NextSignalButton.Visible = 'off';
            app.NextSignalButton.Tooltip = {'Next signal is available once the current signal has been filtered.'};
            app.NextSignalButton.Text = 'Next Signal';
            app.ResistorValueLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.ResistorValueLabel.Text = 'Resistor Value (Ω)';
            app.OscopeReminderLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.OscopeReminderLabel.Enable = 'off';
            app.OscopeReminderLabel.Visible = 'off';
            app.OscopeReminderLabel.Text = 'Remember to take a picture of your oscilloscope screen before advancing to the next signal';
            app.EditFieldLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.EditFieldLabel.Text = 'Attempts Remaining';
            app.AttemptsRemainingEditField = uieditfield(app.FilterTrainerAppUIFigure, 'numeric');
            app.AttemptsRemainingEditField.AllowEmpty = 'on';
            app.AttemptsRemainingEditField.Editable = 'off';
            app.AttemptsRemainingEditField.Value = [];
            % Show the figure after all components are created
            app.FilterTrainerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterTrainerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilterTrainerAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilterTrainerAppUIFigure)
        end
    end
end